classdef bml_databrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadFromWorkspacePreserve_CheckBox  matlab.ui.control.CheckBox
        LoadArtifact_Button          matlab.ui.control.Button
        TrialsPanel                  matlab.ui.container.Panel
        TrialsSelectOk_Button        matlab.ui.control.Button
        TrialSelect_ListBox          matlab.ui.control.ListBox
        Label_3                      matlab.ui.control.Label
        Task_EditField               matlab.ui.control.EditField
        TaskEditFieldLabel           matlab.ui.control.Label
        Session_EditField            matlab.ui.control.EditField
        SessionEditFieldLabel        matlab.ui.control.Label
        Subject_EditField            matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        JumptosLabel                 matlab.ui.control.Label
        TimeGlobal_Field             matlab.ui.control.NumericEditField
        RegionofinterestPanel        matlab.ui.container.Panel
        ROINew_Button_2              matlab.ui.control.Button
        ROIPwelchChannel_EditField   matlab.ui.control.EditField
        ROIPwelch_Button             matlab.ui.control.Button
        ROIZoomToTime_Button         matlab.ui.control.Button
        ROINew_Button                matlab.ui.control.Button
        ChannelsPanel                matlab.ui.container.Panel
        ChannelsECoG_ListBox         matlab.ui.control.ListBox
        ECoGLabel                    matlab.ui.control.Label
        ChannelsOther_ListBox        matlab.ui.control.ListBox
        OtherLabel                   matlab.ui.control.Label
        ChannelsSelectOk_Button      matlab.ui.control.Button
        LoadPanel                    matlab.ui.container.Panel
        LoadButton                   matlab.ui.control.Button
        View_DropDown                matlab.ui.control.DropDown
        ViewDropDownLabel            matlab.ui.control.Label
        LoadFromWorkspace_EditField  matlab.ui.control.EditField
        LoadfromworkspaceLabel       matlab.ui.control.Label
        MessageBox_Label             matlab.ui.control.Label
        LoadAnnot_Button             matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        LocalizeTab                  matlab.ui.container.Tab
        ElecEditField                matlab.ui.control.EditField
        ElecEditFieldLabel           matlab.ui.control.Label
        UIAxesLocalize               matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        NavigatePanel                matlab.ui.container.Panel
        SpansEditFieldLabel          matlab.ui.control.Label
        TimeSpan_Field               matlab.ui.control.NumericEditField
        TimeScroll_Field             matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        ScaleLabel                   matlab.ui.control.Label
        ScrollLabel                  matlab.ui.control.Label
        TimeRight_Button             matlab.ui.control.Button
        TimeLeft_Button              matlab.ui.control.Button
        VertScaleDown_Button         matlab.ui.control.Button
        VertScaleUp_Button           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu_2                       matlab.ui.container.Menu
        Menu2_2                      matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        Menu_3                       matlab.ui.container.Menu
        Menu2_3                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        % TODO 
        % add the command line output to the bottom of the app with a
        % diary.txt: https://www.mathworks.com/matlabcentral/answers/630313-how-to-display-command-window-in-app-designer-in-real-time
        % 
        % set xlim t

        ft_orig % fieldtrip object containing data
        ft % currently displayed fieldtrip
        ft_artifact_orig = []
        ft_artifact = []
        SelectedChannels
        ScaleFactor % nchan x 1, determines the scaling of channels, can be modified
        zlim = [-10 10];
%         TimeSpan = 10 % duration of time currently plotted in the window
        ROI  % rectangular region of interest, struct as defined by drawrectangle() https://www.mathworks.com/help/images/ref/drawrectangle.html
    end
    
    methods (Access = private)
        
        function [] = plotTraces(app, preserve_xlim)
            % this should be called when a) channels change or b) scale
            % changes 

            xl = xlim(app.UIAxes); 

            cla(app.UIAxes)
%             hL = findobj(app.UIAxes, 'type', 'line'); 
%             delete(hL); 

            if strcmp(app.View_DropDown.Value, 'Trialed')
                % convert from {nchan x time} trials to {ntrials x time} channels
                
                try 
                    tmp = cat(3, app.ft.trial{:}); % tmp is chan x time x rpt/trial
                catch e 
                    updateMsgBox(app, 'Data are not trialed appropriately; trialed data must have equal numbers of channels and samples in ft_raw.trial{:}'); 
                    return
                end
                [nchan, ns, nrpts] = size(tmp); 
                
%                 tmp = permute(tmp, [1 3 2]); % switch ordering to chan x rpt/trial x time 
%                 tmp = num2cell(tmp, [2 3]); 
%                 tmp = cellfun(@squeeze, tmp, 'UniformOutput', false);
                   
                Y = squeeze(tmp(1, :, :)); 
%                 Y = Y ./ app.ScaleFactor(strcmp(app.ft_orig.label, app.ft.label(1))); 
                Y = Y ./ app.ScaleFactor; 

                time = repmat(app.ft.time{1}', [1 nrpts]);

                ntraces = nrpts;
                lbls = convertStringsToChars(string(1:nrpts))';
                if ~iscell(lbls); lbls = {lbls}; end
            elseif strcmp(app.View_DropDown.Value, 'Raw')
                nchan  = length(app.ft.label);
                
                Y = app.ft.trial{1}';  % samples x channels
    %             mins = min(Y, [], 1); 
    %             maxs = max(Y, [], 1); 
                Y = Y ./ app.ScaleFactor; 
    
                time = repmat(app.ft.time{1}', [1 nchan]);

                ntraces = nchan;
                lbls = app.ft.label; 
            elseif strcmp(app.View_DropDown.Value, 'Time-Frequency')
                cfg = []; 
                cfg.hax = app.UIAxes; 
                cfg.baseline = 'none';
                cfg.savefig = 'no';
                cfg.zlim = app.zlim; % 'auto'; % [-20 20];
                cfg.xlim = [-4 4];
%                 aa = repmat(sapp.ScaleFactor(:), [1 size(app.ft.powspctrm, 4)]); 
%                 app.ft.powspctrm = bsxfun(@minus, app.ft.powspctrm, app.ScaleFactor(:,1)); % ./ app.ScaleFactor(:,2);
                aa = (squeeze(app.ft.powspctrm) - app.ScaleFactor(:,1)) ./ (app.ScaleFactor(:,2) + eps); 
                app.ft.powspctrm(1, 1, :, :) = aa; 
%                 aa = app.ft.powspctrm(1, 1, :, :) - app.ScaleFactor(:,1); 
                bml_plb_singleplotTFR(cfg, app.ft);
            end


            if strcmp(app.View_DropDown.Value, 'Trialed') || strcmp(app.View_DropDown.Value, 'Raw')


                hLPR = LinePlotReducer(@plot, app.UIAxes, time, Y + (1:ntraces));
                set(hLPR.h_plot, {'DisplayName'}, lbls);

                if ~isempty(app.ft_artifact)
                    Y(~logical(app.ft_artifact.trial{1}')) = nan;
                    hLPR = LinePlotReducer(@plot, app.UIAxes, time, Y + (1:ntraces), 'r', 'linewidth', 2);
                    set(hLPR.h_plot, {'DisplayName'}, cellfun(@(s) [s '-artifact'] , app.ft.label , 'UniformOutput' , false))
                end


                %             plot(app.UIAxes, time, Y + (1:nchan));

                ylim(app.UIAxes, [0 (ntraces+1)]);
                yticks(app.UIAxes, (1:ntraces));
                yticklabels(app.UIAxes, lbls);

                if preserve_xlim
                    xlim(app.UIAxes, xl)
                else
                    xlim(app.UIAxes, time(1) + [0 app.TimeSpan_Field.Value]);
                end
            end


        end
        
        function results = updateMsgBox(app, msg)
            app.MessageBox_Label.Text = ['>> ' msg]; 
        end
        
        function results = updateSelectedData(app)
           
            switch app.View_DropDown.Value
                case 'Raw'
                    app.SelectedChannels = [app.ChannelsOther_ListBox.Value(:); app.ChannelsECoG_ListBox.Value(:)]; 
                case {'Trialed', 'Time-Frequency'}
                    chansother = app.ChannelsOther_ListBox.Value; 
                    chansecog = app.ChannelsECoG_ListBox.Value; 
                    chan = [chansother chansecog];
                    assert(any(strcmp(app.ft_orig.label, chan))); % make sure we have just a single channel
                    app.SelectedChannels = chan; 
            end

            cfg = []; 
            cfg.channel = app.SelectedChannels;
            cfg.trials = app.TrialSelect_ListBox.Value;
            app.ft = ft_selectdata(cfg, app.ft_orig);
            
            switch app.View_DropDown.Value
                case {'Raw', 'Trialed'}
                    app.ScaleFactor = prctile(abs(app.ft.trial{1}'), 99.5, 1); 
                case {'Time-Frequency'}
                    cfg = []; 
                    cfg.channel = app.SelectedChannels;
                    fttmp = ft_selectdata(cfg, app.ft_orig);
                    pow = squeeze(fttmp.powspctrm);

%                     m1 = median(pow, 1, 'omitnan');
%                     m2 = median(m1, 4, 'omitnan');

                    pow = permute(pow, [2 3 1]); % freq x time x chan x rpt
                    freq_alltime = reshape(pow, size(pow, 1), []); 
                    app.ScaleFactor = [median(freq_alltime, 2, 'omitnan') mad(freq_alltime, 1, 2)];
%                     app.ScaleFactor = squeeze(m2); % one value for each frequency
            end
            
            plotTraces(app, false); 
                          
%             xl = xlim(app.UIAxes); 
%             plotTraces(app, true); 
%             xlim(app.UIAxes, xl); 

%             if exist('reset_xlim_flag', 'var') & reset_xlim_flag
%                 xlim(app.UIAxes, app.ft.time{1}(1) + ); 
%             end
        end
        
        function results = updateXLim(app, src, evt)
            xticklabels(app.UIAxes, 'auto'); 
            xl = xlim(app.UIAxes); 
            
%             margin_left = 0.1; 
%             margin_right = 0.1; 
%             t0 = xl(1) + diff(xl)*0.1;
%             t1 = xl(2) - diff(xl)*0.1;
            t0 = xl(1);
            t1 = xl(2);
            xticks(app.UIAxes, linspace(t0, t1, 5)); 
            xt = xticks(app.UIAxes); 

            switch app.View_DropDown.Value
                case 'Raw'
                    xticklabels(app.UIAxes, xt - xt(1)); 
                case 'Trialed'
                    % nothing to do 
                    xticks(app.UIAxes, [-1 -0.5 0 0.5 1]*min(abs(xl))); 
            end
            app.TimeGlobal_Field.Value = xt(1); 
            app.TimeSpan_Field.Value = diff(xl); 
        end
        
        function results = loadNewData(app)
            app.ft = app.ft_orig; 
            
            if strcmp(app.View_DropDown.Value, 'Raw')
                cfg = [];
                cfg.detrend = 'yes';
                app.ft_orig = ft_preprocessing(cfg, app.ft_orig);

            end
                
            if any(strcmp(app.View_DropDown.Value, {'Raw', 'Trialed'}))
                    ntrials = length(app.ft_orig.trial); 
            elseif any(strcmp(app.View_DropDown.Value, {'Time-Frequency'}))
                ntrials = size(app.ft_orig.powspctrm, 1); 
            end

            % update the number of trials according to the FT object
%             app.Trial_Label.Text = sprintf('Trial (x/%d)', length(app.ft_orig.trial)); 
%             app.TrialSelect_ListBox.Label = sprintf('Trial'); 


            app.TrialSelect_ListBox.ItemsData = (1:ntrials); 
            app.TrialSelect_ListBox.Items = string(1:ntrials);
            app.TrialSelect_ListBox.Value = app.TrialSelect_ListBox.ItemsData(1); 
            
%             if app.LoadFromWorkspaceTrialView_CheckBox.Value
%                 idxs = 1:min(10, length(app.ft_orig.trial));
%                 app.TrialSelect_ListBox.Value = app.TrialSelect_ListBox.ItemsData(idxs); 
% 
%                 % add multiselect option
% %             app.TrialSelect_ListBox.Label = sprintf('Trial (multiselect)'); 
%             end

            ich_ecog = startsWith(app.ft.label, 'ecog'); 
            app.ChannelsECoG_ListBox.Items = app.ft.label(ich_ecog); 
            app.ChannelsOther_ListBox.Items = app.ft.label(~ich_ecog); 
            
            inl_ecog = find(ich_ecog, 10); 
            inl_other = find(~ich_ecog, 1); 
            if inl_ecog
%                 app.ChannelsECoG_ListBox.Value = app.ft.label(inl_ecog); 
                app.ChannelsECoG_ListBox.Value = {}; 

            end
            if inl_other
                app.ChannelsOther_ListBox.Value = app.ft.label(inl_other); 
            end     

            updateSelectedData(app); % updates channel selection, reset time flag 

            addlistener(app.UIAxes, 'XLim', 'PostSet', @(src, evt) updateXLim(app, src, evt))

            plotTraces(app, false);

%             LinePlotExplorer(app.UIAxes);
        end
        
        function results = func6(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            zoom(app.UIAxes, 'off'); 
            set(app.UIAxes, 'TickLabelInterpreter', 'none');
            set(app.UIAxes, 'box', 'on');
            set(app.UIAxes, 'ydir', 'normal'); % normal or reverse
            disableDefaultInteractivity( app.UIAxes ); 

            pan(app.UIAxes, 'off'); 
            hold(app.UIAxes, 'on'); 

%             set(app.UIAxes, 'Xlabel', 'Time [s]');
            xlabel(app.UIAxes, 'Time [s]'); 
            ylabel(app.UIAxes, ''); 

%             app.Subject_EditField.Value = evalin('base', 'SUBJECT');
%             app.Session_EditField.Value = evalin('base', 'SESSION');
%             app.Task_EditField.Value = evalin('base', 'TASK');

            plot_colors = [91,139,103;
                           43,143,179] ./ 255; 
            plot_colors = plot_colors + 0.2; % increase intensity
            colororder(app.UIAxes, plot_colors); 

            xlim(app.UIAxes, [0 1]*app.TimeSpan_Field.Value);

%             nchan = 20;
%             ns = 150e3;
%             fs = 1000;
% 
%             app.ft.trsial{1} = rand(nchan, ns);
%             app.ft.time{1} =  (1:ns)/fs;
%             app.ft.trial{2} = rand(nchan, ns);
%             app.ft.time{2} =  (1:ns)/fs;
% 
%             app.ft.label = cellstr(repmat("ecog_L", [nchan, 1]) + string(1:nchan)'); 


        end

        % Button pushed function: TimeRight_Button
        function TimeRight_ButtonPushed(app, event)
            if any(strcmp(app.View_DropDown.Value, {'Raw', 'Trialed'}))
                xl = get(app.UIAxes, 'xlim');
                set(app.UIAxes, 'xlim', xl + (diff(xl)*app.TimeScroll_Field.Value/100)); 
            elseif strcmp(app.View_DropDown.Value, {'Time-Frequency'})
                app.TrialSelect_ListBox.Value = app.TrialSelect_ListBox.ItemsData(find(app.TrialSelect_ListBox.Value==app.TrialSelect_ListBox.ItemsData) + 1);
                updateSelectedData(app);
            end

        end

        % Button pushed function: TimeLeft_Button
        function TimeLeft_ButtonPushed(app, event)
            if any(strcmp(app.View_DropDown.Value, {'Raw', 'Trialed'}))
                xl = get(app.UIAxes, 'xlim');
                set(app.UIAxes, 'xlim', xl - (diff(xl)*app.TimeScroll_Field.Value/100)); 
            elseif strcmp(app.View_DropDown.Value, {'Time-Frequency'})
                app.TrialSelect_ListBox.Value = app.TrialSelect_ListBox.ItemsData(find(app.TrialSelect_ListBox.Value==app.TrialSelect_ListBox.ItemsData) - 1);
                updateSelectedData(app);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
            	TimeLeft_ButtonPushed(app)
            elseif strcmp(key,'rightarrow')
                TimeRight_ButtonPushed(app)
            elseif strcmp(key,'uparrow')
                VertScaleUp_ButtonPushed(app)            
            elseif strcmp(key,'downarrow')
                VertScaleDown_ButtonPushed(app)
            end
            
        end

        % Callback function
        function TrialSelect_FieldValueChanged2(app, event)
            
            cfg = []; 
            cfg.trials = app.TrialSelect_Field.Value; 
            cfg.channel = app.SelectedChannels; 
            app.ft = ft_selectdata(cfg, app.ft_orig); 

            plotTraces(app, false); 
        end

        % Button pushed function: VertScaleUp_Button
        function VertScaleUp_ButtonPushed(app, event)
            if any(strcmp(app.View_DropDown.Value, {'Raw', 'Trialed'}))
                app.ScaleFactor = app.ScaleFactor/2;
            elseif strcmp(app.View_DropDown.Value, {'Time-Frequency'})
                app.zlim = app.zlim*2; 
                
            end
%             updateSelectedData(app);
            plotTraces(app, true); % this is now nested in updateSelectedData()

        end

        % Button pushed function: VertScaleDown_Button
        function VertScaleDown_ButtonPushed(app, event)
            if any(strcmp(app.View_DropDown.Value, {'Raw', 'Trialed'}))
                app.ScaleFactor = app.ScaleFactor*2;
            elseif strcmp(app.View_DropDown.Value, {'Time-Frequency'})
                app.zlim = app.zlim/2;
            end
%             updateSelectedData(app);
            plotTraces(app, true); % this is now nested in updateSelectedData()
        end

        % Button pushed function: ChannelsSelectOk_Button
        function ChannelsSelectOk_ButtonPushed(app, event)

            updateSelectedData(app)
        end

        % Value changed function: TimeSpan_Field
        function TimeSpan_FieldValueChanged(app, event)

            xl = xlim(app.UIAxes); 
            xlim(app.UIAxes, xl(1) + app.TimeSpan_Field.Value*[0 1]); 
        end

        % Value changed function: TimeGlobal_Field
        function TimeGlobal_FieldValueChanged(app, event)
            xl = xlim(app.UIAxes); 
            xlim(app.UIAxes, app.TimeGlobal_Field.Value + [0 diff(xl)] - 0.1*diff(xl));
            app.TimeGlobal_Field.Value = 0.0;
        end

        % Callback function
        function TrialIncrease_ButtonPushed(app, event)
            app.TrialSelect_Field.Value = app.TrialSelect_Field.Value + 1; 
            TrialSelect_FieldValueChanged2(app); 
        end

        % Callback function
        function TrialDecrease_ButtonPushed(app, event)
            app.TrialSelect_Field.Value = app.TrialSelect_Field.Value - 1; 
            TrialSelect_FieldValueChanged2(app); 

        end

        % Value changed function: ChannelsECoG_ListBox
        function ChannelsECoG_ListBoxValueChanged(app, event)
            value = app.ChannelsECoG_ListBox.Value;
            
        end

        % Button pushed function: LoadAnnot_Button
        function LoadAnnot_ButtonPushed(app, event)
            % [file,path] = uigetfile(['Y:\DBS\derivatives\sub-' app.Subject_EditField.Value '\annot\*.tsv']);  %open a mat file;
            [file,path] = uigetfile('*.tsv');

            focus(app.UIFigure);
            if ~file; updateMsgBox(app, ['File not found: ' path file]); return; end


            annot = bml_annot_read_tsv([path file]);
            if all(abs(annot.duration-0) < 1e-10) % we are plotting events with no duration
%                 LinePlotReducer(@xline, app.UIAxes, annot.starts)
                xline(app.UIAxes, annot.starts, '--k');
            else % plot events with fixed duration
                X = [annot.starts annot.ends annot.ends annot.starts];
                Y = repmat([-5 -5 100 100], [height(annot) 1]);
                hold(app.UIAxes, 'on');
                patch(app.UIAxes, X', Y', 'r', 'faceColor', [1 1 1]*0.2, 'facealpha', 0.2, 'edgecolor', 'k', 'linewidth', 3);
            end
            %             LinePlotReducer(@path, app.UIAxes, [t1 t2 t2 t1], [-5 -5 100 100],'r', 'facecolor', [1 1 1]*0.2, 'facealpha', 0.2, 'edgecolor', 'none'); 
% 
%             for ia = 1:height(annot)
%                 t1 = annot.starts(ia); 
%                 t2 = annot.ends(ia);
%                 
%                 LinePlotReducer(@fill, app.UIAxes, [t1 t2 t2 t1], [-5 -5 100 100],'r', 'facecolor', [1 1 1]*0.2, 'facealpha', 0.2, 'edgecolor', 'none'); 
%                
%             end'
            
            % move most recently plotted item to the background
            hC = get(app.UIAxes, 'children'); 
            set(app.UIAxes, 'Children', [hC(2:end); hC(1)]); 

            set(0, 'CurrentFigure', app.UIFigure); 
        end

        % Value changed function: LoadFromWorkspace_EditField
        function LoadFromWorkspace_EditFieldValueChanged(app, event)
%             xl = xlim(app.UIAxes); 
%             app.ft_orig = evalin('base', app.LoadFromWorkspace_EditField.Value); 
%             loadNewData(app); 
%             
%             if app.LoadFromWorkspacePreserve_CheckBox.Value
%                 xlim(app.UIAxes, xl); 
%             end

        end

        % Value changed function: ElecEditField
        function ElecEditFieldValueChanged(app, event)
            elec = app.ElecEditField.Value;
            sub = app.Subject_EditField.Value; 
            ses = app.Session_EditField.Value; 

            p = ['Y:\DBS\derivatives\sub-' sub '\analysis\ecog-localization-native-space\' ...
                'figures\single-elec-highlighted\sub-' sub '_ses-' ses '_space-native_elec-' elec '.png'];
            if ~exist(p, 'file') 
                updateMsgBox(app, ['File not found: ' p]); 
                return;
            end
            loc = imread(p); 
            imshow(loc, 'Parent', app.UIAxesLocalize); 

        end

        % Callback function
        function TrialSelect_FieldValueChanged3(app, event)

            cfg = []; 
            cfg.trials = app.TrialSelect_ListBox.Value; 
            cfg.channel = app.SelectedChannels; 
            app.ft = ft_selectdata(cfg, app.ft_orig); 

            plotTraces(app, false); 
        end

        % Button pushed function: LoadArtifact_Button
        function LoadArtifact_ButtonPushed(app, event)
            % [file,path] = uigetfile(['Y:\DBS\derivatives\sub-' app.Subject_EditField.Value '\annot\*.tsv']);  %open a mat file;
            % [file,path] = uigetfile({'*.*';'*.txt.gz';'*.txt';});
            [file,path] = uigetfile({'*'});

            
            focus(app.UIFigure);
            if ~file; updateMsgBox(app, ['File not found: ' path file]); return; end
            
            artifacts = bml_annot_read_tsv([path file]);
            updateMsgBox(app, 'Loading arfifacts...');
%             ich = 1; 
%             for ich = 1:length(app.ft_orig.label)
%                 arttmp = artifacts(strcmpi(artifacts.ch_name, app.ft_orig.label{ich}), :); 
%                 cfg = []; 
%                 cfg.label_colname = 'ch_name'; 
% %                 cfg.annot_label = app.ft_orig.label{ich}; 
%                 cfg.template = app.ft_orig; 
% 
%                 app.ft_artifact = bml_annot2raw(cfg, arttmp); 
%             end

            if ismember('label',artifacts.Properties.VariableNames) 
                artifacts = renamevars(artifacts, 'label', 'channel');
            end
            if ismember('status',artifacts.Properties.VariableNames) 
                artifacts(artifacts.status=="good", :) = []; 
            end
            cfg = []; 
            cfg.label_colname = 'channel'; 
%                 cfg.annot_label = app.ft_orig.label{ich}; 
            cfg.template = app.ft_orig; 
   
            updateMsgBox(app, 'Converting arfifacts to ft_raw...');
            app.ft_artifact_orig = bml_annot2raw(cfg, artifacts); 

            % cfg.label - cellstr, names of channels in new raw. Defaults to 'annot' if
%     annot_label_colname is not provided. 
% cfg.annot_label - string, indicates channel on which events should be
%     added. Defaults to cfg.label{1}. 
% cfg.label_colname - cellstr, indicating name of column of annot
%     containging the channel's label the current annotation should be
%     added to. If not given, all annotations are added 
%     to same channel defined by annot_label.  
% cfg.annot_label_colname - (deprecated) same as label_colname 
% cfg.template - raw to use as template
% cfg.count - boolean indicating if number in raw should indicate number of
%     annotations at that time point. Defaults to true. If false,
%     presence/absence is reported. 
            cfg = []; 
            cfg.channel = app.SelectedChannels;
            cfg.trials = app.TrialSelect_ListBox.Value;
            app.ft_artifact = ft_selectdata(cfg, app.ft_artifact_orig);
            
            updateMsgBox(app, 'Plotting traces...');
            plotTraces(app, true); 
            updateMsgBox(app, 'Done...');

        end

        % Button pushed function: ROINew_Button
        function ROINew_ButtonPushed(app, event)
            app.ROI = drawrectangle(app.UIAxes); 
            roi_tlims = app.ROI.Position(1) + [0 app.ROI.Position(3)];
            roi_ylims = app.ROI.Position(2) + [0 app.ROI.Position(4)];

            hL = findobj(app.UIAxes,'type', 'line');
            for i = 1:length(hL)
                t = get(hL(i), 'xdata');
                y = get(hL(i), 'ydata');
                mask_time = t>roi_tlims(1) & t<roi_tlims(2);
                mask_yvals = y>roi_ylims(1) & y<roi_ylims(2); 
                mask  = mask_time & mask_yvals; 
                
                if all(mask_yvals(mask_time))
                    plot(app.UIAxes, t(mask), y(mask), 'linewidth', 4, 'color', get(hL(i), 'color'));
                end
            end

        end

        % Button pushed function: ROIZoomToTime_Button
        function ROIZoomToTime_ButtonPushed(app, event)
            if isempty(app.ROI)
                updateMsgBox(app, 'No ROI selected. Create new ROI. ');
            end
            xlim(app.UIAxes, app.ROI.Position(1) + [0 app.ROI.Position(3)]); 
        end

        % Button pushed function: ROIPwelch_Button
        function ROIPwelch_ButtonPushed(app, event)
%             hL = findobj(app.UIAxes, 'type', 'line'); 
            toi = app.ROI.Position(1) + [0 app.ROI.Position(3)]; 
            ft_tmp = bml_crop(app.ft, toi(1), toi(2)); 

            cfg = []; 
            cfg.channel = app.ROIPwelchChannel_EditField.Value; 
            ft_tmp = ft_selectdata(cfg, ft_tmp); 
            foi = 10.^(0.30:0.045:2.4);
            hF=figure; hA = axes(hF); 
            pwelch(ft_tmp.trial{1}, [], [], foi, app.ft.fsample); 
            set(hA, 'xscale', 'log'); 

        end

        % Value changed function: TrialSelect_ListBox
        function TrialSelect_ListBoxValueChanged(app, event)

        end

        % Button pushed function: ROINew_Button_2
        function ROINew_Button_2Pushed(app, event)
            hF = figure; 
            copyobj(app.UIAxes, hF); 
            export_fig('./test', '-eps', hF);

        end

        % Value changed function: View_DropDown
        function View_DropDownValueChanged(app, event)
            value = app.View_DropDown.Value;
            
            switch app.View_DropDown.Value
                case 'Raw'
                    set([app.ChannelsECoG_ListBox, app.ChannelsOther_ListBox], 'Multiselect', true);
                    set([app.TrialSelect_ListBox ], 'Multiselect', false);
                case 'Trialed'
                    set([app.ChannelsECoG_ListBox, app.ChannelsOther_ListBox], 'Multiselect', false);
                    if ~isempty(app.ChannelsECoG_ListBox.Items)
                        app.ChannelsECoG_ListBox.Value = app.ChannelsECoG_ListBox.Items(1); 
                        app.ChannelsOther_ListBox.Value = {};
                    else
                        app.ChannelsOther_ListBox.Value = app.ChannelsOther_ListBox.Items(1);
                        app.ChannelsECoG_ListBox.Value = {}; 
                    end
                    
                    set([app.TrialSelect_ListBox], 'Multiselect', true);
                    ntrs = min(30, length(app.TrialSelect_ListBox.ItemsData)); 
                    app.TrialSelect_ListBox.Value = app.TrialSelect_ListBox.ItemsData(1:ntrs);
                case 'Time-Frequency'
                    set([app.ChannelsECoG_ListBox, app.ChannelsOther_ListBox], 'Multiselect', false);
                    set([app.TrialSelect_ListBox ], 'Multiselect', true);

            end
%             updateSelectedData(app); 
%             plotTraces(app, false);

        end

        % Button pushed function: TrialsSelectOk_Button
        function TrialsSelectOk_ButtonPushed(app, event)
            updateSelectedData(app); 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            xl = xlim(app.UIAxes); 
            app.ft_orig = evalin('base', app.LoadFromWorkspace_EditField.Value); 
            loadNewData(app); 
            
            if app.LoadFromWorkspacePreserve_CheckBox.Value
                xlim(app.UIAxes, xl); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title', 'Interpreter', 'tex')
            xlabel(app.UIAxes, 'X', 'Interpreter', 'tex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'tex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'tex')
            app.NavigatePanel = uipanel(app.UIFigure);
            app.NavigatePanel.Title = 'Navigate';
            app.VertScaleUp_Button = uibutton(app.NavigatePanel, 'push');
            app.VertScaleUp_Button.ButtonPushedFcn = createCallbackFcn(app, @VertScaleUp_ButtonPushed, true);
            app.VertScaleUp_Button.Text = '▲';
            app.VertScaleDown_Button = uibutton(app.NavigatePanel, 'push');
            app.VertScaleDown_Button.ButtonPushedFcn = createCallbackFcn(app, @VertScaleDown_ButtonPushed, true);
            app.VertScaleDown_Button.Text = '▼';
            app.TimeLeft_Button = uibutton(app.NavigatePanel, 'push');
            app.TimeLeft_Button.ButtonPushedFcn = createCallbackFcn(app, @TimeLeft_ButtonPushed, true);
            app.TimeLeft_Button.Text = '◀';
            app.TimeRight_Button = uibutton(app.NavigatePanel, 'push');
            app.TimeRight_Button.ButtonPushedFcn = createCallbackFcn(app, @TimeRight_ButtonPushed, true);
            app.TimeRight_Button.Text = '▶';
            app.ScrollLabel = uilabel(app.NavigatePanel);
            app.ScrollLabel.Text = 'Scroll';
            app.ScaleLabel = uilabel(app.NavigatePanel);
            app.ScaleLabel.Text = 'Scale';
            app.Label_2 = uilabel(app.NavigatePanel);
            app.Label_2.Text = '%';
            app.Label = uilabel(app.NavigatePanel);
            app.Label.Text = '%';
            app.TimeScroll_Field = uieditfield(app.NavigatePanel, 'numeric');
            app.TimeScroll_Field.Value = 50;
            app.TimeSpan_Field = uieditfield(app.NavigatePanel, 'numeric');
            app.TimeSpan_Field.ValueChangedFcn = createCallbackFcn(app, @TimeSpan_FieldValueChanged, true);
            app.TimeSpan_Field.Value = 10;
            app.SpansEditFieldLabel = uilabel(app.NavigatePanel);
            app.SpansEditFieldLabel.Text = 'Span [s]';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LocalizeTab = uitab(app.TabGroup);
            app.LocalizeTab.Title = 'Localize';
            app.UIAxesLocalize = uiaxes(app.LocalizeTab);
            zlabel(app.UIAxesLocalize, 'Z', 'Interpreter', 'tex')
            app.UIAxesLocalize.XTick = [];
            app.UIAxesLocalize.YTick = [];
            app.UIAxesLocalize.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; ''};
            app.ElecEditFieldLabel = uilabel(app.LocalizeTab);
            app.ElecEditFieldLabel.Text = 'Elec (↵)';
            app.ElecEditField = uieditfield(app.LocalizeTab, 'text');
            app.ElecEditField.ValueChangedFcn = createCallbackFcn(app, @ElecEditFieldValueChanged, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image = uiimage(app.Tab2);
            app.LoadAnnot_Button = uibutton(app.UIFigure, 'push');
            app.LoadAnnot_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadAnnot_ButtonPushed, true);
            app.LoadAnnot_Button.Text = 'Load Annot';
            app.MessageBox_Label = uilabel(app.UIFigure);
            app.MessageBox_Label.Text = ' >>';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Title = 'Load';
            app.LoadfromworkspaceLabel = uilabel(app.LoadPanel);
            app.LoadfromworkspaceLabel.Text = 'FieldTrip raw name';
            app.LoadFromWorkspace_EditField = uieditfield(app.LoadPanel, 'text');
            app.LoadFromWorkspace_EditField.ValueChangedFcn = createCallbackFcn(app, @LoadFromWorkspace_EditFieldValueChanged, true);
            app.ViewDropDownLabel = uilabel(app.LoadPanel);
            app.ViewDropDownLabel.Text = 'View';
            app.View_DropDown = uidropdown(app.LoadPanel);
            app.View_DropDown.Items = {'Raw', 'Trialed', 'Time-Frequency'};
            app.View_DropDown.ValueChangedFcn = createCallbackFcn(app, @View_DropDownValueChanged, true);
            app.View_DropDown.Value = 'Time-Frequency';
            app.LoadButton = uibutton(app.LoadPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelsSelectOk_Button = uibutton(app.ChannelsPanel, 'push');
            app.ChannelsSelectOk_Button.ButtonPushedFcn = createCallbackFcn(app, @ChannelsSelectOk_ButtonPushed, true);
            app.ChannelsSelectOk_Button.Text = 'Ok';
            app.OtherLabel = uilabel(app.ChannelsPanel);
            app.OtherLabel.Text = 'Other';
            app.ChannelsOther_ListBox = uilistbox(app.ChannelsPanel);
            app.ChannelsOther_ListBox.Multiselect = 'on';
            app.ChannelsOther_ListBox.Value = {'Item 1'};
            app.ECoGLabel = uilabel(app.ChannelsPanel);
            app.ECoGLabel.Text = 'ECoG';
            app.ChannelsECoG_ListBox = uilistbox(app.ChannelsPanel);
            app.ChannelsECoG_ListBox.Multiselect = 'on';
            app.ChannelsECoG_ListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsECoG_ListBoxValueChanged, true);
            app.ChannelsECoG_ListBox.Value = {'Item 1'};
            app.RegionofinterestPanel = uipanel(app.UIFigure);
            app.RegionofinterestPanel.Title = ' Region of interest';
            app.ROINew_Button = uibutton(app.RegionofinterestPanel, 'push');
            app.ROINew_Button.ButtonPushedFcn = createCallbackFcn(app, @ROINew_ButtonPushed, true);
            app.ROINew_Button.Text = 'New';
            app.ROIZoomToTime_Button = uibutton(app.RegionofinterestPanel, 'push');
            app.ROIZoomToTime_Button.ButtonPushedFcn = createCallbackFcn(app, @ROIZoomToTime_ButtonPushed, true);
            app.ROIZoomToTime_Button.Text = 'Zoom to time';
            app.ROIPwelch_Button = uibutton(app.RegionofinterestPanel, 'push');
            app.ROIPwelch_Button.ButtonPushedFcn = createCallbackFcn(app, @ROIPwelch_ButtonPushed, true);
            app.ROIPwelch_Button.Text = 'pwelch()';
            app.ROIPwelchChannel_EditField = uieditfield(app.RegionofinterestPanel, 'text');
            app.ROIPwelchChannel_EditField.Placeholder = 'Channel Name';
            app.ROINew_Button_2 = uibutton(app.RegionofinterestPanel, 'push');
            app.ROINew_Button_2.ButtonPushedFcn = createCallbackFcn(app, @ROINew_Button_2Pushed, true);
            app.ROINew_Button_2.Text = 'Remove';
            app.TimeGlobal_Field = uieditfield(app.UIFigure, 'numeric');
            app.TimeGlobal_Field.ValueDisplayFormat = '%.1f';
            app.TimeGlobal_Field.ValueChangedFcn = createCallbackFcn(app, @TimeGlobal_FieldValueChanged, true);
            app.JumptosLabel = uilabel(app.UIFigure);
            app.JumptosLabel.Text = 'Jump to [global, s]';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.Subject_EditField = uieditfield(app.UIFigure, 'text');
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.Session_EditField = uieditfield(app.UIFigure, 'text');
            app.TaskEditFieldLabel = uilabel(app.UIFigure);
            app.TaskEditFieldLabel.Text = 'Task';
            app.Task_EditField = uieditfield(app.UIFigure, 'text');
            app.TrialsPanel = uipanel(app.UIFigure);
            app.TrialsPanel.Title = 'Trials';
            app.Label_3 = uilabel(app.TrialsPanel);
            app.Label_3.Text = '''''';
            app.TrialSelect_ListBox = uilistbox(app.TrialsPanel);
            app.TrialSelect_ListBox.Items = {};
            app.TrialSelect_ListBox.Multiselect = 'on';
            app.TrialSelect_ListBox.ValueChangedFcn = createCallbackFcn(app, @TrialSelect_ListBoxValueChanged, true);
            app.TrialSelect_ListBox.Value = {};
            app.TrialsSelectOk_Button = uibutton(app.TrialsPanel, 'push');
            app.TrialsSelectOk_Button.ButtonPushedFcn = createCallbackFcn(app, @TrialsSelectOk_ButtonPushed, true);
            app.TrialsSelectOk_Button.Text = 'Ok';
            app.LoadArtifact_Button = uibutton(app.UIFigure, 'push');
            app.LoadArtifact_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadArtifact_ButtonPushed, true);
            app.LoadArtifact_Button.Text = 'Load Artifact';
            app.LoadFromWorkspacePreserve_CheckBox = uicheckbox(app.UIFigure);
            app.LoadFromWorkspacePreserve_CheckBox.Text = 'Preserve time';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu2);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu2);
            app.Menu2_3.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bml_databrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end