classdef bml_databrowser_202304031152 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadArtifact_Button          matlab.ui.control.Button
        MessageBox_Label             matlab.ui.control.Label
        Task_EditField               matlab.ui.control.EditField
        TaskEditFieldLabel           matlab.ui.control.Label
        Session_EditField            matlab.ui.control.EditField
        SessionEditFieldLabel        matlab.ui.control.Label
        Subject_EditField            matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        LoadFromWorkspacePreserve_CheckBox  matlab.ui.control.CheckBox
        LoadFromWorkspace_EditField  matlab.ui.control.EditField
        LoadfromworkspaceLabel       matlab.ui.control.Label
        LoadAnnot_Button             matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        LocalizeTab                  matlab.ui.container.Tab
        ElecEditField                matlab.ui.control.EditField
        ElecEditFieldLabel           matlab.ui.control.Label
        UIAxesLocalize               matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        ChannelsOther_ListBox        matlab.ui.control.ListBox
        OtherLabel                   matlab.ui.control.Label
        JumptosLabel                 matlab.ui.control.Label
        TimeGlobal_Field             matlab.ui.control.NumericEditField
        ChannelsECoG_ListBox         matlab.ui.control.ListBox
        ECoGLabel                    matlab.ui.control.Label
        NavigatePanel                matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        TrialSelect_Field            matlab.ui.control.Spinner
        SpansEditFieldLabel          matlab.ui.control.Label
        TimeSpan_Field               matlab.ui.control.NumericEditField
        TimeScroll_Field             matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        ScrollLabel                  matlab.ui.control.Label
        Trial_Label_2                matlab.ui.control.Label
        Trial_Label                  matlab.ui.control.Label
        TimeRight_Button             matlab.ui.control.Button
        TimeLeft_Button              matlab.ui.control.Button
        VertScaleDown_Button         matlab.ui.control.Button
        VertScaleUp_Button           matlab.ui.control.Button
        Trial_Label_3                matlab.ui.control.Label
        ChannelsSelectOk_Button      matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ft_orig % fieldtrip object containing data
        ft % currently displayed fieldtrip
        ft_artifact = []
        SelectedChannels
        ScaleFactor % nchan x 1, determines the scaling of channels, can be modified
%         TimeSpan = 10 % duration of time currently plotted in the window
    end
    
    methods (Access = private)
        
        function [] = plotTraces(app, preserve_xlim)
            % this should be called when a) channels change or b) scale
            % changes 
            xl = xlim(app.UIAxes); 

%             cla(app.UIAxes)
            hL = findobj(app.UIAxes, 'type', 'line'); 
            delete(hL); 

            nchan  = length(app.ft.label);
            
            Y = app.ft.trial{1}';  % samples x channels
%             mins = min(Y, [], 1); 
%             maxs = max(Y, [], 1); 
            Y = Y ./ app.ScaleFactor(ismember(app.ft_orig.label, app.ft.label)); 

            time = repmat(app.ft.time{1}', [1 nchan]);

            LinePlotReducer(@plot, app.UIAxes, time, Y + (1:nchan));
            
            if ~isempty(app.ft_artifact)
                hold(app.UIAxes, 'on'); 

                cfg = []; 
                cfg.channel = app.SelectedChannels;
                cfg.trials = app.TrialSelect_Field.Value;
                ft_artifact_tmp = ft_selectdata(cfg, app.ft_artifact);

                Y(~logical(ft_artifact_tmp.trial{1}')) = nan; 
                LinePlotReducer(@plot, app.UIAxes, time, Y + (1:nchan), 'r', 'linewidth', 2);
            end

%             plot(app.UIAxes, time, Y + (1:nchan)); 

            ylim(app.UIAxes, [0 (nchan+1)]); 
            yticks(app.UIAxes, 1:nchan); 
            yticklabels(app.UIAxes, app.ft.label); 

            if preserve_xlim
                xlim(app.UIAxes, xl)
            else 
                xlim(app.UIAxes, time(1) + [0 app.TimeSpan_Field.Value]); 
            end

        end
        
        function results = updateMsgBox(app, msg)
            app.MessageBox_Label.Text = ['>> ' msg]; 
        end
        
        function results = updateSelectedChannels(app)
            app.SelectedChannels = [app.ChannelsOther_ListBox.Value(:); app.ChannelsECoG_ListBox.Value(:)]; 

            cfg = []; 
            cfg.channel = app.SelectedChannels;
            cfg.trials = app.TrialSelect_Field.Value;
            app.ft = ft_selectdata(cfg, app.ft_orig);

            plotTraces(app, true); 
                          
%             xl = xlim(app.UIAxes); 
%             plotTraces(app, true); 
%             xlim(app.UIAxes, xl); 

%             if exist('reset_xlim_flag', 'var') & reset_xlim_flag
%                 xlim(app.UIAxes, app.ft.time{1}(1) + ); 
%             end
        end
        
        function results = updateXLim(app, src, evt)
            xticklabels(app.UIAxes, 'auto'); 
            xl = xlim(app.UIAxes); 

            t0 = xl(1) + diff(xl)*0.1;
            t1 = xl(2) - diff(xl)*0.1;
            xticks(app.UIAxes, linspace(t0, t1, 3)); 

            xt = xticks(app.UIAxes); 
            xticklabels(app.UIAxes, xt - xt(1)); 
            
            app.TimeGlobal_Field.Value = xt(1); 
        end
        
        function results = loadNewData(app)
            app.ft = app.ft_orig; 

            cfg = [];
            cfg.detrend = 'yes'; 
            app.ft_orig = ft_preprocessing(cfg, app.ft_orig); 
            app.ScaleFactor = prctile(abs(app.ft_orig.trial{1}'), 99.5, 1); 

            % update the number of trials according to the FT object
            app.Trial_Label.Text = sprintf('Trial (x/%d)', length(app.ft_orig.trial)); 
            
            ich_ecog = startsWith(app.ft.label, 'ecog'); 
            app.ChannelsECoG_ListBox.Items = app.ft.label(ich_ecog); 
            app.ChannelsOther_ListBox.Items = app.ft.label(~ich_ecog); 
            
            inl_ecog = find(ich_ecog, 10); 
            inl_other = find(~ich_ecog, 1); 
            if inl_ecog
                app.ChannelsECoG_ListBox.Value = app.ft.label(inl_ecog); 
            end
            if inl_other
                app.ChannelsOther_ListBox.Value = app.ft.label(inl_other); 
            end        

            updateSelectedChannels(app); % updates channel selection, reset time flag 

            addlistener(app.UIAxes, 'XLim', 'PostSet', @(src, evt) updateXLim(app, src, evt))

            plotTraces(app, false);

%             LinePlotExplorer(app.UIAxes);
        end
        
        function results = func6(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            zoom(app.UIAxes, 'off'); 
            set(app.UIAxes, 'TickLabelInterpreter', 'none');
            set(app.UIAxes, 'box', 'on');
            set(app.UIAxes, 'ydir', 'reverse');
            disableDefaultInteractivity( app.UIAxes ); 

            pan(app.UIAxes, 'off')
%             set(app.UIAxes, 'Xlabel', 'Time [s]');
            xlabel(app.UIAxes, 'Time [s]'); 
            ylabel(app.UIAxes, ''); 

            app.Subject_EditField.Value = evalin('base', 'SUBJECT');
            app.Session_EditField.Value = evalin('base', 'SESSION');
            app.Task_EditField.Value = evalin('base', 'TASK');

%             nchan = 20;
%             ns = 150e3;
%             fs = 1000;
% 
%             app.ft.trsial{1} = rand(nchan, ns);
%             app.ft.time{1} =  (1:ns)/fs;
%             app.ft.trial{2} = rand(nchan, ns);
%             app.ft.time{2} =  (1:ns)/fs;
% 
%             app.ft.label = cellstr(repmat("ecog_L", [nchan, 1]) + string(1:nchan)'); 


        end

        % Button pushed function: TimeRight_Button
        function TimeRight_ButtonPushed(app, event)

            xl = get(app.UIAxes, 'xlim');
            set(app.UIAxes, 'xlim', xl + (diff(xl)*app.TimeScroll_Field.Value/100)); 
        end

        % Button pushed function: TimeLeft_Button
        function TimeLeft_ButtonPushed(app, event)
            xl = get(app.UIAxes, 'xlim');
            set(app.UIAxes, 'xlim', xl - (diff(xl)*app.TimeScroll_Field.Value/100)); 

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
            	TimeLeft_ButtonPushed(app)
            elseif strcmp(key,'rightarrow')
                TimeRight_ButtonPushed(app)
            elseif strcmp(key,'uparrow')
                VertScaleUp_ButtonPushed(app)            
            elseif strcmp(key,'downarrow')
                VertScaleDown_ButtonPushed(app)
            end
            
        end

        % Callback function
        function TrialSelect_FieldValueChanged2(app, event)
            
            cfg = []; 
            cfg.trials = app.TrialSelect_Field.Value; 
            cfg.channel = app.SelectedChannels; 
            app.ft = ft_selectdata(cfg, app.ft_orig); 

            plotTraces(app, false); 
        end

        % Button pushed function: VertScaleUp_Button
        function VertScaleUp_ButtonPushed(app, event)
            app.ScaleFactor = app.ScaleFactor/2;
            plotTraces(app, true);
        end

        % Button pushed function: VertScaleDown_Button
        function VertScaleDown_ButtonPushed(app, event)
            app.ScaleFactor = app.ScaleFactor*2;
            plotTraces(app, true);
        end

        % Button pushed function: ChannelsSelectOk_Button
        function ChannelsSelectOk_ButtonPushed(app, event)

            updateSelectedChannels(app)
        end

        % Value changed function: TimeSpan_Field
        function TimeSpan_FieldValueChanged(app, event)

            xl = xlim(app.UIAxes); 
            xlim(app.UIAxes, app.TimeSpan_Field.Value*[-1 1]/2 + mean(xl)); 
        end

        % Value changed function: TimeGlobal_Field
        function TimeGlobal_FieldValueChanged(app, event)
            xl = xlim(app.UIAxes); 
            xlim(app.UIAxes, app.TimeGlobal_Field.Value + [0 diff(xl)] - 0.1*diff(xl));
            app.TimeGlobal_Field.Value = 0.0;
        end

        % Callback function
        function TrialIncrease_ButtonPushed(app, event)
            app.TrialSelect_Field.Value = app.TrialSelect_Field.Value + 1; 
            TrialSelect_FieldValueChanged2(app); 
        end

        % Value changed function: ChannelsECoG_ListBox
        function ChannelsECoG_ListBoxValueChanged(app, event)
            value = app.ChannelsECoG_ListBox.Value;
            
        end

        % Callback function
        function TrialDecrease_ButtonPushed(app, event)
            app.TrialSelect_Field.Value = app.TrialSelect_Field.Value - 1; 
            TrialSelect_FieldValueChanged2(app); 

        end

        % Button pushed function: LoadAnnot_Button
        function LoadAnnot_ButtonPushed(app, event)
            [file,path] = uigetfile(['Y:\DBS\derivatives\sub-' app.Subject_EditField.Value '\annot\*.tsv']);  %open a mat file;
            focus(app.UIFigure);
            if ~file; return; end

            
            annot = bml_annot_read_tsv([path file]);
            X = [annot.starts annot.ends annot.ends annot.starts];
            Y = repmat([-5 -5 100 100], [height(annot) 1]);
            hold(app.UIAxes, 'on'); 
            patch(app.UIAxes, X', Y', 'r', 'faceColor', [1 1 1]*0.2, 'facealpha', 0.2, 'edgecolor', 'none'); 
%             LinePlotReducer(@path, app.UIAxes, [t1 t2 t2 t1], [-5 -5 100 100],'r', 'facecolor', [1 1 1]*0.2, 'facealpha', 0.2, 'edgecolor', 'none'); 
% 
%             for ia = 1:height(annot)
%                 t1 = annot.starts(ia); 
%                 t2 = annot.ends(ia);
%                 
%                 LinePlotReducer(@fill, app.UIAxes, [t1 t2 t2 t1], [-5 -5 100 100],'r', 'facecolor', [1 1 1]*0.2, 'facealpha', 0.2, 'edgecolor', 'none'); 
%                
%             end
            set(app.UIAxes, 'Children', flipud(get(app.UIAxes, 'children'))); 

            set(0, 'CurrentFigure', app.UIFigure); 
        end

        % Value changed function: LoadFromWorkspace_EditField
        function LoadFromWorkspace_EditFieldValueChanged(app, event)
            xl = xlim(app.UIAxes); 
            app.ft_orig = evalin('base', app.LoadFromWorkspace_EditField.Value); 
            loadNewData(app); 
            
            if app.LoadFromWorkspacePreserve_CheckBox.Value
                xlim(app.UIAxes, xl); 
            end
        end

        % Value changed function: ElecEditField
        function ElecEditFieldValueChanged(app, event)
            elec = app.ElecEditField.Value;
            sub = app.Subject_EditField.Value; 
            ses = app.Session_EditField.Value; 

            p = ['Y:\DBS\derivatives\sub-' sub '\analysis\ecog-localization-native-space\' ...
                'figures\single-elec-highlighted\sub-' sub '_ses-' ses '_space-native_elec-' elec '.png'];
            if ~exist(p, 'file') 
                updateMsgBox(app, [p ': File not found ! ']); 
                return;
            end
            loc = imread(p); 
            imshow(loc, 'Parent', app.UIAxesLocalize); 

        end

        % Value changed function: TrialSelect_Field
        function TrialSelect_FieldValueChanged3(app, event)

            cfg = []; 
            cfg.trials = app.TrialSelect_Field.Value; 
            cfg.channel = app.SelectedChannels; 
            app.ft = ft_selectdata(cfg, app.ft_orig); 

            plotTraces(app, false); 
        end

        % Button pushed function: LoadArtifact_Button
        function LoadArtifact_ButtonPushed(app, event)
            [file,path] = uigetfile(['Y:\DBS\derivatives\sub-' app.Subject_EditField.Value '\annot\*.tsv']);  %open a mat file;
            focus(app.UIFigure);
             if ~file; return; end
            
            artifacts = bml_annot_read_tsv([path file]);
            
%             ich = 1; 
%             for ich = 1:length(app.ft_orig.label)
%                 arttmp = artifacts(strcmpi(artifacts.ch_name, app.ft_orig.label{ich}), :); 
%                 cfg = []; 
%                 cfg.label_colname = 'ch_name'; 
% %                 cfg.annot_label = app.ft_orig.label{ich}; 
%                 cfg.template = app.ft_orig; 
% 
%                 app.ft_artifact = bml_annot2raw(cfg, arttmp); 
%             end

            cfg = []; 
            cfg.label_colname = 'ch_name'; 
%                 cfg.annot_label = app.ft_orig.label{ich}; 
            cfg.template = app.ft_orig; 
   

            app.ft_artifact = bml_annot2raw(cfg, artifacts); 


            % cfg.label - cellstr, names of channels in new raw. Defaults to 'annot' if
%     annot_label_colname is not provided. 
% cfg.annot_label - string, indicates channel on which events should be
%     added. Defaults to cfg.label{1}. 
% cfg.label_colname - cellstr, indicating name of column of annot
%     containging the channel's label the current annotation should be
%     added to. If not given, all annotations are added 
%     to same channel defined by annot_label.  
% cfg.annot_label_colname - (deprecated) same as label_colname 
% cfg.template - raw to use as template
% cfg.count - boolean indicating if number in raw should indicate number of
%     annotations at that time point. Defaults to true. If false,
%     presence/absence is reported. 


            plotTraces(app, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChannelsSelectOk_Button = uibutton(app.UIFigure, 'push');
            app.ChannelsSelectOk_Button.ButtonPushedFcn = createCallbackFcn(app, @ChannelsSelectOk_ButtonPushed, true);
            app.ChannelsSelectOk_Button.Text = 'Ok';
            app.NavigatePanel = uipanel(app.UIFigure);
            app.NavigatePanel.Title = 'Navigate';
            app.GridLayout2 = uigridlayout(app.NavigatePanel);
            app.Trial_Label_3 = uilabel(app.GridLayout2);
            app.Trial_Label_3.Text = 'Vertical Scale';
            app.VertScaleUp_Button = uibutton(app.GridLayout2, 'push');
            app.VertScaleUp_Button.ButtonPushedFcn = createCallbackFcn(app, @VertScaleUp_ButtonPushed, true);
            app.VertScaleUp_Button.Text = '▲';
            app.VertScaleDown_Button = uibutton(app.GridLayout2, 'push');
            app.VertScaleDown_Button.ButtonPushedFcn = createCallbackFcn(app, @VertScaleDown_ButtonPushed, true);
            app.VertScaleDown_Button.Text = '▼';
            app.TimeLeft_Button = uibutton(app.GridLayout2, 'push');
            app.TimeLeft_Button.ButtonPushedFcn = createCallbackFcn(app, @TimeLeft_ButtonPushed, true);
            app.TimeLeft_Button.Text = '◀';
            app.TimeRight_Button = uibutton(app.GridLayout2, 'push');
            app.TimeRight_Button.ButtonPushedFcn = createCallbackFcn(app, @TimeRight_ButtonPushed, true);
            app.TimeRight_Button.Text = '▶';
            app.Trial_Label = uilabel(app.GridLayout2);
            app.Trial_Label.Text = 'Trial';
            app.Trial_Label_2 = uilabel(app.GridLayout2);
            app.Trial_Label_2.Text = 'Time';
            app.ScrollLabel = uilabel(app.GridLayout2);
            app.ScrollLabel.Text = 'Scroll';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '%';
            app.TimeScroll_Field = uieditfield(app.GridLayout2, 'numeric');
            app.TimeScroll_Field.Value = 50;
            app.TimeSpan_Field = uieditfield(app.GridLayout2, 'numeric');
            app.TimeSpan_Field.ValueChangedFcn = createCallbackFcn(app, @TimeSpan_FieldValueChanged, true);
            app.TimeSpan_Field.Value = 10;
            app.SpansEditFieldLabel = uilabel(app.GridLayout2);
            app.SpansEditFieldLabel.Text = 'Span [s]';
            app.TrialSelect_Field = uispinner(app.GridLayout2);
            app.TrialSelect_Field.ValueChangedFcn = createCallbackFcn(app, @TrialSelect_FieldValueChanged3, true);
            app.TrialSelect_Field.Value = 1;
            app.ECoGLabel = uilabel(app.UIFigure);
            app.ECoGLabel.Text = 'ECoG';
            app.ChannelsECoG_ListBox = uilistbox(app.UIFigure);
            app.ChannelsECoG_ListBox.Multiselect = 'on';
            app.ChannelsECoG_ListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsECoG_ListBoxValueChanged, true);
            app.ChannelsECoG_ListBox.Value = {'Item 1'};
            app.TimeGlobal_Field = uieditfield(app.UIFigure, 'numeric');
            app.TimeGlobal_Field.ValueDisplayFormat = '%.1f';
            app.TimeGlobal_Field.ValueChangedFcn = createCallbackFcn(app, @TimeGlobal_FieldValueChanged, true);
            app.JumptosLabel = uilabel(app.UIFigure);
            app.JumptosLabel.Text = 'Jump to [global, s]';
            app.OtherLabel = uilabel(app.UIFigure);
            app.OtherLabel.Text = 'Other';
            app.ChannelsOther_ListBox = uilistbox(app.UIFigure);
            app.ChannelsOther_ListBox.Multiselect = 'on';
            app.ChannelsOther_ListBox.Value = {'Item 1'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LocalizeTab = uitab(app.TabGroup);
            app.LocalizeTab.Title = 'Localize';
            app.UIAxesLocalize = uiaxes(app.LocalizeTab);
            zlabel(app.UIAxesLocalize, 'Z')
            app.UIAxesLocalize.XTick = [];
            app.UIAxesLocalize.YTick = [];
            app.UIAxesLocalize.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; ''};
            app.ElecEditFieldLabel = uilabel(app.LocalizeTab);
            app.ElecEditFieldLabel.Text = 'Elec (↵)';
            app.ElecEditField = uieditfield(app.LocalizeTab, 'text');
            app.ElecEditField.ValueChangedFcn = createCallbackFcn(app, @ElecEditFieldValueChanged, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image = uiimage(app.Tab2);
            app.LoadAnnot_Button = uibutton(app.UIFigure, 'push');
            app.LoadAnnot_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadAnnot_ButtonPushed, true);
            app.LoadAnnot_Button.Text = 'Load Annot';
            app.LoadfromworkspaceLabel = uilabel(app.UIFigure);
            app.LoadfromworkspaceLabel.Text = 'Load from workspace (↵)';
            app.LoadFromWorkspace_EditField = uieditfield(app.UIFigure, 'text');
            app.LoadFromWorkspace_EditField.ValueChangedFcn = createCallbackFcn(app, @LoadFromWorkspace_EditFieldValueChanged, true);
            app.LoadFromWorkspacePreserve_CheckBox = uicheckbox(app.UIFigure);
            app.LoadFromWorkspacePreserve_CheckBox.Text = 'Preserve time';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.Subject_EditField = uieditfield(app.UIFigure, 'text');
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.Session_EditField = uieditfield(app.UIFigure, 'text');
            app.TaskEditFieldLabel = uilabel(app.UIFigure);
            app.TaskEditFieldLabel.Text = 'Task';
            app.Task_EditField = uieditfield(app.UIFigure, 'text');
            app.MessageBox_Label = uilabel(app.UIFigure);
            app.MessageBox_Label.Text = ' >>';
            app.LoadArtifact_Button = uibutton(app.UIFigure, 'push');
            app.LoadArtifact_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadArtifact_ButtonPushed, true);
            app.LoadArtifact_Button.Text = 'Load Artifact';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bml_databrowser_202304031152

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end